classdef falcons < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FAstconversiontooLbetweenCONstrainSUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        IOPanel                         matlab.ui.container.Panel
        SINEXFileEditFieldLabel         matlab.ui.control.Label
        SINEXFileEditField              matlab.ui.control.EditField
        loadButton                      matlab.ui.control.Button
        ControlSitesButtonGroup         matlab.ui.container.ButtonGroup
        FixFileButton                   matlab.ui.control.RadioButton
        UseallstationsButton            matlab.ui.control.RadioButton
        extractfromSinexFileButton      matlab.ui.control.RadioButton
        loadFixButton                   matlab.ui.control.Button
        SinexToAscIIButton              matlab.ui.control.Button
        loadAscIIblocksButton           matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        SetNewConditionsPanel           matlab.ui.container.Panel
        GoButton                        matlab.ui.control.Button
        translationmeterNNTEditFieldLabel  matlab.ui.control.Label
        translationmeterNNTEditField    matlab.ui.control.NumericEditField
        rotationmeterNNREditFieldLabel  matlab.ui.control.Label
        rotationmeterNNREditField       matlab.ui.control.NumericEditField
        initialminimalconstrainButton   matlab.ui.control.Button
        loadMenu                        matlab.ui.container.Menu
        SinexMenu                       matlab.ui.container.Menu
        coordinateListMenu              matlab.ui.container.Menu
        FIXlistMenu                     matlab.ui.container.Menu
        ExampleMenu                     matlab.ui.container.Menu
        blocksMenu                      matlab.ui.container.Menu
        convertMenu                     matlab.ui.container.Menu
        loadblocksMenu                  matlab.ui.container.Menu
        constrainMenu                   matlab.ui.container.Menu
        restoreInitialconstrainMenu     matlab.ui.container.Menu
        applynewconstrainMenu           matlab.ui.container.Menu
        saveMenu                        matlab.ui.container.Menu
        matFileMenu                     matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
         % Description 
            DirSnx  % directory of the Sinex file
            DirAsc  % directory of the ASCII folder convert each SINEX block
            DirFix  % Directory of the fidutial sites list
            DirCrd  % directory of the CRD file
            SnxFile % Sinex file name
            FixFile % Fix file name
            FixLst  % Fix station list
            CrdFile % CRD file name 
            Blkfmt  % block format
            ExpStr  % Expression String
            Blktype % block type
            VarFactor  % variance factor
            
            % data block
            CocOrgApr   % aprior covariance matrix
            CocOrgEst   % Estimated covariance matrix
            SolEst      % Solution estimated
            SolApr      % solution aprior
            Sites       % station list
            H_con       % flag of the control station
            H_rm        % flag for removing any parameter which is not station coordinates
            CoordEst    % coordinate estimated
            CoordApr    % coordinate Aprior
            U           % right hand side
            dx          % dx -> estimated parameter differeneces
            
            % initial minimal constrain
            CocIMC=[];      % co-variance matrix of Initil minimal constrain (IMC)
            dxIMC =[];      % dx Initil minimal constrain
            NIMC  =[];      % normal matrix of the IMC
            
            % Over Constrain condition
            CocOC=[];       % covariance matrix of specified over constrain
            dxOC=[];
            NOC =[];
            
            % constrain sigma unit meter
            SigT =0;       % sigma for the No-Net translation
            SigR =0;       % sigma for the No-Net rotation
            SigS =0;       % sigma for the No-Net Scale
            
            % No-net constrain matrix
            E=[];
            QE=[];
            
            % constant
            C_ControlAprior = 1e-20;   % threshhold to find the control station list
            C_EarthRadius   = 6371000; % mean Earth radius
            
            % user opt
            U_FixTyp =0;   % option for the control sites 0:no selection; 1- Fix file; 2- All staion 3- From sinex file
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SinexFileMenuSelected(app, event)
           
        end

        % Callback function
        function FidutiallistMenuSelected(app, event)
         

        end

        % Menu selected function: coordinateListMenu
        function coordinateListMenuSelected(app, event)
            [app.CrdFile,app.DirCrd]=uigetfile({'*.CRD';'*.*'},'File Selector');
            fprintf(['loaded coordiante file: ',app.CrdFile]);
        end

        % Menu selected function: convertMenu
        function convertMenuSelected(app, event)
            
            % set the path to save the ASCII file (covert from the SINEX file)
            [~,SnxName] = fileparts(app.SnxFile);
            if ~exist(fullfile(app.DirSnx,'tmp',SnxName),'dir')
                answer = questdlg('ASCII file directory is not found, Use default path?', ...
                    'Block AscII file path',...
	                'Default', 'Custrom','Cancel' ,'Dedault');
                switch answer
                    case 'Default'
                        mkdir(fullfile(app.DirSnx,'tmp',SnxName));
                        app.DirAsc=fullfile(app.DirSnx,'tmp',SnxName,filesep);
                    case 'Custrom'
                        app.DirAsc = uigetdir;
                    case 'Cancel' 
                        fprintf('Warning: AscDir is not set up yet!');
                        return
                end
            else
                app.DirAsc=fullfile(app.DirSnx,'tmp',SnxName,filesep);
            end
            if isempty(app.DirAsc)
                   disp('ASCII folder is not defined, please give setup the folder before further proceed! \n');
                   return;
            end
            disp(['ASCII file stored at: ',char(app.DirAsc),'\n']);
            disp(['-- read: ',char(app.DirSnx),'-----\n']);
            disp([' big files (e.g. ITRF2014 SNX) will take a considerable time, please be patient ']);
            sepsnx( char(fullfile(app.DirSnx,app.SnxFile)),char(app.DirAsc) );
            disp(['-- slpit SINEX file finished -----']);
            
            
        end

        % Menu selected function: FIXlistMenu
        function FIXlistMenuSelected(app, event)
              [app.FixFile,app.DirFix]=uigetfile({'*.FIX';'*.*'},'File Selector');
            fprintf(['loaded fidutial site list: ',app.FixFile]);
            
            %read the Fix list into the Falcon
            if exist(fullfile(app.DirFix,app.FixFile),'file')
                filstr = fileread(fullfile(app.DirFix,app.FixFile)); 
                fix_list = strsplit(filstr,'\n');
                while(isempty(fix_list{end}))
                    fix_list(end)=[];
                end
                
                fix_list = cellfun(@strtrim, fix_list, 'uni', false);
                app.FixLst = fix_list;
                fprintf('the Fix station list:\n');
                fprintf('%s \n',app.FixLst{:} );
            else
                disp(['The FIX file is not found, please try again!']);
            end
            app.U_FixTyp = 1;
        end

        % Menu selected function: SinexMenu
        function SinexMenuSelected(app, event)
             % read new sinex file, at the same time reset the ASCII folder
            [app.SnxFile,app.DirSnx]=uigetfile({'*.SNX';'*.SINEX';'*.*'},'File Selector');
            disp(['loaded SINEX file: ',app.SnxFile]);
            app.DirAsc = [];
            
            app.SINEXFileEditField.Value = char(app.SnxFile);
        end

        % Menu selected function: loadblocksMenu
        function loadblocksMenuSelected(app, event)
            %--------------------
            % load the scale factor
            var_LC = 1; % default
            
            % load the variance factor; if not available then give value 1
            blktype = 'SOLUTION/STATISTICS';
            [blockformat,exp_str ]= loadblkfmt (blktype);
            
            if exist(fullfile(app.DirAsc,'/SOLUTION_STATISTICS.txt'),'file')
                [data_out ] = readsnxblk(fullfile(app.DirAsc,'/SOLUTION_STATISTICS.txt'), blockformat,exp_str);
                [sol_stat] = convblk(data_out,blktype);
                H_VarFactor = strcmpi(sol_stat{1,1},'VARIANCE FACTOR');
                var_LC = sol_stat{1,2}(H_VarFactor);
            end
            
            
            %-------------------------
            % load the covariance aprior
            blkfile = fullfile(app.DirAsc,'/SOLUTION_MATRIX_APRIORI L COVA.txt');
            if exist(blkfile,'file')
                blktype = 'SOLUTION/MATRIX_APRIORI L COVA';
                [blockformat,exp_str ]= loadblkfmt (blktype);
                [data_out ] = readsnxblk(fullfile(app.DirAsc,'/SOLUTION_MATRIX_APRIORI L COVA.txt'), blockformat,exp_str);
                [Coc_LC_ap] = convblk(data_out,blktype);
                Coc_LC_ap=Coc_LC_ap./var_LC;
            else
                blkfile =fullfile(app.DirAsc,'/SOLUTION_MATRIX_APRIORI L INFO.txt'); 
                if exist(blkfile,'file')
                    blktype = 'SOLUTION/MATRIX_APRIORI L INFO';
                    [blockformat,exp_str ]= loadblkfmt (blktype);
                    [data_out ] = readsnxblk(fullfile(app.DirAsc,'/SOLUTION_MATRIX_APRIORI L INFO.txt'), blockformat,exp_str);
                    [Coc_LC_ap] = convblk(data_out,blktype); % read the N_constra (not a convariance matrix)
                    Coc_LC_ap=Coc_LC_ap./var_LC;
                else
                    Coc_LC_ap = [];
                end
            end
            
            
            %------------------------------------------------------
            % load the estiamted covariance 
            blktype = 'SOLUTION/MATRIX_ESTIMATE L COVA';
            [blockformat,exp_str ]= loadblkfmt (blktype);
            [data_out ] = readsnxblk(fullfile(app.DirAsc,'/SOLUTION_MATRIX_ESTIMATE L COVA.txt'), blockformat,exp_str);
            [Coc_LC] = convblk(data_out,blktype);
            Coc_LC=Coc_LC./var_LC;
            
            
            blktype = 'SOLUTION/ESTIMATE';
            [blockformat,exp_str ]= loadblkfmt (blktype);
            [data_out ] = readsnxblk(fullfile(app.DirAsc,'/SOLUTION_ESTIMATE.txt'), blockformat,exp_str);
            [sol_est] = convblk(data_out,blktype);
            

            %--------------------------------
            % load the solution aprior block
            if exist(fullfile(app.DirAsc,'/SOLUTION_APRIORI.txt'),'file')
                blktype = 'SOLUTION/APRIORI';
                [blockformat,exp_str ]= loadblkfmt (blktype);
                [data_out ] = readsnxblk(fullfile(app.DirAsc,'/SOLUTION_APRIORI.txt'), blockformat,exp_str);
                [sol_apr] = convblk(data_out,blktype);
            else
                sol_apr=[];
                disp(['SOLUTION/APRIORI block missing!']);
            end
            
            
            %-----------------------------------
            % load Site/ID block
            blktype = 'SITE/ID';
            [blockformat,exp_str ]= loadblkfmt (blktype);
            [data_out ] = readsnxblk(fullfile(app.DirAsc,'/SITE_ID.txt'), blockformat,exp_str);
            [sites] = convblk(data_out,blktype);


            %-------------------------------------
            % remove the non coordiante parameter
            H_rm = strcmp(sol_est{1,2},'STAX') |  strcmp(sol_est{1,2},'STAY') |strcmp(sol_est{1,2},'STAZ');
            H_rm = ~H_rm;
            
            if ~isempty(sol_apr)  
                for idx = 1:length(sol_apr), sol_apr{1,idx}(H_rm) = [];end
            end
            for idx = 1:length(sol_est), sol_est{1,idx}(H_rm) = [];end
            
            coord_est = sol_est{1,9};
            coord_apr = sol_apr{1,9};
            % ---------------------------------------
            % get the Fix station flag matrix
            if isempty(app.FixLst)
                % if no FIX list is given then ask the user to give the
                % choice of the FIX list
                if app.U_FixTyp==0
                    answer = questdlg('No Fix file is given, automatically detect the control station from aprior covariance block?', ...
                        'extract control staion information',...
    	                'Use Sinex block', 'Give the FIX station now','All stations are control sites','Cancel' ,'Use Sinex block');
                else
                    switch app.U_FixTyp
                        case 1
                            answer='Give the FIX station now';
                        case 2
                            answer='All stations are control sites';
                        case 3
                            answer='Use Sinex block';
                    end
                end
                
                switch answer
                    case 'Use Sinex block'
                        
                        H_con = sum(log10(abs(Coc_LC_ap))>app.C_ControlAprior)>1;
                        H_con = logical(padmat(H_con,[0,(length(sol_est{1,2}) -length(H_con))],0));
                        app.extractfromSinexFileButton.Value=true   ;
                        
                    case 'Give the FIX station now'
                        app.FIXlistMenuSelected(app)
                        H_con = false(size(sol_est{1,3})); % fidusial sites : 1-> fix station 0 not
                        for idx = 1:length(app.FixLst)
                            H_i = strcmp(sol_est{1,3},app.FixLst{idx}(1:4)) ;
                            H_con = H_con|H_i;
                        end
                        app.FixFileButton.Value = true;
                    case 'All stations are control sites'
                        H_con = True(size(coord_est));
                        app.UseallstationsButton.Value=true;
                        
                    case 'Cancel'
                        fprintf('Warning: control station information is not given, software stop!');
                        return
                end
                
                 
            else
                % use the Fix file to set the Fix station list
                H_con = false(size(sol_est{1,3})); % fidusial sites : 1-> fix station 0 not
                for idx = 1:length(app.FixLst)
                    H_i = strcmp(sol_est{1,3},app.FixLst{idx}(1:4)) ;
                    H_con = H_con|H_i;
                end
            end
            %------------------------------------
            % assign data to the app properties
            app.VarFactor=var_LC;
            fprintf('The variance Factor of the covariance matrix is: %f',app.VarFactor);
            app.CocOrgApr = Coc_LC_ap; % set up the aroprior covariance matrix
            app.SolApr = sol_apr;
            app.Sites=sites;
            app.SolEst = sol_est;
            app.CocOrgEst = Coc_LC;
            app.H_rm = H_rm;
            app.H_con = H_con;
            app.CoordEst = coord_est;
            app.CoordApr = coord_apr;
            app.dx       = coord_est-coord_apr;
            app.U        = inv(Coc_LC)*app.dx; 
        end

        % Menu selected function: ExampleMenu
        function ExampleMenuSelected(app, event)
            app.SnxFile = "SIR16887.SNX";
            app.DirSnx  = fullfile('.','examples');
            app.FixFile = 'SIR16887.FIX';
            app.DirFix  = fullfile('.','examples');
            app.DirAsc  = fullfile('.','examples','tmp','SIR16887',filesep);
            % load Fix list
            filstr = fileread(fullfile(app.DirFix,app.FixFile)); 
            fix_list = strsplit(filstr,'\n');
            while(isempty(fix_list{end}))
                fix_list(end)=[];
            end
            
            fix_list = cellfun(@strtrim, fix_list, 'uni', false);
            app.FixLst = fix_list;
            app.SINEXFileEditField.Value=char(app.SnxFile);
        end

        % Menu selected function: restoreInitialconstrainMenu
        function restoreInitialconstrainMenuSelected(app, event)
            %N_initl = inv(Coc_LC)-inv(Coc_LC_ap);   % normal matrix ? N(Loose constrain)-N(aprior)    [inital matrix] minimal constrain
    
            N_initl = inv(app.CocOrgEst);
            if ~isempty(app.CocOrgApr)
                N_initl = N_initl - inv(app.CocOrgApr);
               
            end
            Coc_initl = inv(N_initl);
            dx_initl = Coc_initl*app.U;
            app.CocIMC = Coc_initl;
            app.dxIMC = dx_initl;
        end

        % Menu selected function: applynewconstrainMenu
        function applynewconstrainMenuSelected(app, event)
            % use the new constrain condition to apply new constrain on to
            % network
            coord_apr = app.CoordApr;
            H_con = app.H_con;
            
            statnum = length(app.H_con)/3;

            % take the NNT and NNR sigma from app
            
            app.SigT = app.translationmeterNNTEditField.Value;
            app.SigR = app.rotationmeterNNREditField.Value;
            app.SigR = app.SigR/app.C_EarthRadius;
            
            if app.SigT~=0
                E = repmat(eye(3),statnum,1);
                E(~H_con,:) = 0; % keep constrained parameter
                QE = app.SigT^2*ones(3,1);
            else
                E=[];
                QE=[];
            end
            if app.SigR~=0
                E_nnr = zeros(statnum*3,3);
                E_nnr(2:3:end,1)=-coord_apr(3:3:end);
                E_nnr(3:3:end,1)= coord_apr(2:3:end);
                E_nnr(1:3:end,2)= coord_apr(3:3:end);
                E_nnr(3:3:end,2)=-coord_apr(1:3:end);
                E_nnr(1:3:end,3)=-coord_apr(2:3:end);
                E_nnr(2:3:end,3)= coord_apr(1:3:end);
                E_nnr(~H_con,:) = 0; % keep constrained parameter
                QE_nnr = app.SigR^2*ones(3,1);
            else
                E_nnr=[];
                QE_nnr=[];
            end
            E = horzcat(E,E_nnr); % NNT+NNR
            QE = diag([QE;QE_nnr]);

            % FALCONS
            
            G = inv(E'*E)*E';
            dxOC = (eye(statnum*3)-app.CocIMC*G'*inv(QE+G*app.CocIMC*G')*G)*app.dxIMC; % dxOC_new
            
            CG = QE;  % size 6*6
            GCG = G*app.CocIMC*G';  % size:6*6
            K = inv(CG+GCG);      % size: 6*6
            DP =app.CocIMC*G'*K*G; % size 609*609
            P_cov = DP*app.CocIMC; % size 609*609
            CocOC = app.CocIMC-P_cov;
            
            app.E =E;
            app.QE=QE;
            app.CocOC = CocOC;
            app.dxOC  = dxOC;
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            SinexMenuSelected(app)
        end

        % Button pushed function: loadFixButton
        function loadFixButtonPushed(app, event)
            if app.FixFileButton.Value
                FIXlistMenuSelected(app)
                app.U_FixTyp =1;
            end
            if app.UseallstationsButton.Value
                app.U_FixTyp =2;
                app.FixLst=[];
            end
            if app.extractfromSinexFileButton.Value
                app.U_FixTyp=3;
                app.FixLst=[];
            end
        end

        % Button pushed function: SinexToAscIIButton
        function SinexToAscIIButtonPushed(app, event)
            convertMenuSelected(app);
        end

        % Button pushed function: loadAscIIblocksButton
        function loadAscIIblocksButtonPushed(app, event)
            loadblocksMenuSelected(app);
        end

        % Button pushed function: initialminimalconstrainButton
        function initialminimalconstrainButtonPushed(app, event)
            restoreInitialconstrainMenuSelected(app);
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            applynewconstrainMenuSelected(app);
        end

        % Menu selected function: matFileMenu
        function matFileMenuSelected(app, event)
            
            % data block
            CocOrgApr=app.CocOrgApr;    % aprior covariance matrix
            CocOrgEst=app.CocOrgEst;    % Estimated covariance matrix
            SolEst   =app.SolEst;       % Solution estimated
            SolApr   =app.SolApr;       % solution aprior
            Sites    =app.Sites;        % station list
            CoordEst =app.CoordEst;     % coordinate estimated
            CoordApr =app.CoordApr;     % coordinate Aprior
            U        =app.U;            % right hand side
            dx       =app.dx;           % dx -> estimated parameter differeneces
            
            % initial minimal constrain
            CocIMC   =app.CocIMC;       % co-variance matrix of Initil minimal constrain (IMC)
            dxIMC    =app.dxIMC;        % dx Initil minimal constrain
            NIMC     =app.NIMC;         % normal matrix of the IMC
            
            % Over Constrain condition
            CocOC    =app.CocOC;   % covariance matrix of specified over constrain
            dxOC     =app.dxOC;
            NOC      =app.NOC;
            
            filter = {'*.mat'};
            [matfile, matpth] = uiputfile(filter);
            
            save(fullfile(matpth,matfile),'CocOrgApr','CocOrgEst','SolEst',...
                'SolApr','Sites','CoordEst','CoordApr','U','dx','CocIMC',...
                'dxIMC','NIMC','CocOC','NOC','dxOC','-mat')
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.FAstconversiontooLbetweenCONstrainSUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {270, 270};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {307, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FAstconversiontooLbetweenCONstrainSUIFigure = uifigure('Visible', 'off');
            app.FAstconversiontooLbetweenCONstrainSUIFigure.Name = 'FAst conversion tooL between CONstrainS';
            app.FAstconversiontooLbetweenCONstrainSUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FAstconversiontooLbetweenCONstrainSUIFigure.Tag = 'constrain, Falcon';
            app.GridLayout = uigridlayout(app.FAstconversiontooLbetweenCONstrainSUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.IOPanel = uipanel(app.LeftPanel);
            app.IOPanel.Title = 'I/O';
            app.SINEXFileEditFieldLabel = uilabel(app.IOPanel);
            app.SINEXFileEditFieldLabel.Text = 'SINEX File';
            app.SINEXFileEditField = uieditfield(app.IOPanel, 'text');
            app.loadButton = uibutton(app.IOPanel, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.ControlSitesButtonGroup = uibuttongroup(app.LeftPanel);
            app.ControlSitesButtonGroup.Title = 'Control Sites';
            app.FixFileButton = uiradiobutton(app.ControlSitesButtonGroup);
            app.FixFileButton.Text = 'Fix File';
            app.FixFileButton.Value = true;
            app.UseallstationsButton = uiradiobutton(app.ControlSitesButtonGroup);
            app.UseallstationsButton.Text = 'Use all stations';
            app.extractfromSinexFileButton = uiradiobutton(app.ControlSitesButtonGroup);
            app.extractfromSinexFileButton.Text = 'extract from Sinex File';
            app.loadFixButton = uibutton(app.ControlSitesButtonGroup, 'push');
            app.loadFixButton.ButtonPushedFcn = createCallbackFcn(app, @loadFixButtonPushed, true);
            app.loadFixButton.Text = 'load';
            app.SinexToAscIIButton = uibutton(app.LeftPanel, 'push');
            app.SinexToAscIIButton.ButtonPushedFcn = createCallbackFcn(app, @SinexToAscIIButtonPushed, true);
            app.SinexToAscIIButton.Text = 'Sinex To AscII';
            app.loadAscIIblocksButton = uibutton(app.LeftPanel, 'push');
            app.loadAscIIblocksButton.ButtonPushedFcn = createCallbackFcn(app, @loadAscIIblocksButtonPushed, true);
            app.loadAscIIblocksButton.Text = 'load AscII blocks';
            app.RightPanel = uipanel(app.GridLayout);
            app.SetNewConditionsPanel = uipanel(app.RightPanel);
            app.SetNewConditionsPanel.Title = 'Set New Conditions';
            app.GoButton = uibutton(app.SetNewConditionsPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.translationmeterNNTEditFieldLabel = uilabel(app.SetNewConditionsPanel);
            app.translationmeterNNTEditFieldLabel.Text = 'translation [meter] NNT';
            app.translationmeterNNTEditField = uieditfield(app.SetNewConditionsPanel, 'numeric');
            app.translationmeterNNTEditField.Value = 0.001;
            app.rotationmeterNNREditFieldLabel = uilabel(app.SetNewConditionsPanel);
            app.rotationmeterNNREditFieldLabel.Text = 'rotation [meter] NNR';
            app.rotationmeterNNREditField = uieditfield(app.SetNewConditionsPanel, 'numeric');
            app.rotationmeterNNREditField.Value = 0.001;
            app.initialminimalconstrainButton = uibutton(app.RightPanel, 'push');
            app.initialminimalconstrainButton.ButtonPushedFcn = createCallbackFcn(app, @initialminimalconstrainButtonPushed, true);
            app.initialminimalconstrainButton.Text = 'initial minimal constrain';
            app.loadMenu = uimenu(app.FAstconversiontooLbetweenCONstrainSUIFigure);
            app.loadMenu.Text = 'load';
            app.SinexMenu = uimenu(app.loadMenu);
            app.SinexMenu.MenuSelectedFcn = createCallbackFcn(app, @SinexMenuSelected, true);
            app.SinexMenu.Text = 'Sinex';
            app.coordinateListMenu = uimenu(app.loadMenu);
            app.coordinateListMenu.MenuSelectedFcn = createCallbackFcn(app, @coordinateListMenuSelected, true);
            app.coordinateListMenu.Text = 'coordinate List';
            app.FIXlistMenu = uimenu(app.loadMenu);
            app.FIXlistMenu.MenuSelectedFcn = createCallbackFcn(app, @FIXlistMenuSelected, true);
            app.FIXlistMenu.Text = 'FIX list';
            app.ExampleMenu = uimenu(app.loadMenu);
            app.ExampleMenu.MenuSelectedFcn = createCallbackFcn(app, @ExampleMenuSelected, true);
            app.ExampleMenu.Text = 'Example';
            app.blocksMenu = uimenu(app.FAstconversiontooLbetweenCONstrainSUIFigure);
            app.blocksMenu.Text = 'blocks';
            app.convertMenu = uimenu(app.blocksMenu);
            app.convertMenu.MenuSelectedFcn = createCallbackFcn(app, @convertMenuSelected, true);
            app.convertMenu.Text = 'convert';
            app.loadblocksMenu = uimenu(app.blocksMenu);
            app.loadblocksMenu.MenuSelectedFcn = createCallbackFcn(app, @loadblocksMenuSelected, true);
            app.loadblocksMenu.Text = 'load blocks';
            app.constrainMenu = uimenu(app.FAstconversiontooLbetweenCONstrainSUIFigure);
            app.constrainMenu.Text = 'constrain';
            app.restoreInitialconstrainMenu = uimenu(app.constrainMenu);
            app.restoreInitialconstrainMenu.MenuSelectedFcn = createCallbackFcn(app, @restoreInitialconstrainMenuSelected, true);
            app.restoreInitialconstrainMenu.Text = 'restore Initial constrain';
            app.applynewconstrainMenu = uimenu(app.constrainMenu);
            app.applynewconstrainMenu.MenuSelectedFcn = createCallbackFcn(app, @applynewconstrainMenuSelected, true);
            app.applynewconstrainMenu.Text = 'apply new constrain';
            app.saveMenu = uimenu(app.FAstconversiontooLbetweenCONstrainSUIFigure);
            app.saveMenu.Text = 'save';
            app.matFileMenu = uimenu(app.saveMenu);
            app.matFileMenu.MenuSelectedFcn = createCallbackFcn(app, @matFileMenuSelected, true);
            app.matFileMenu.Text = '.mat File';
            % Show the figure after all components are created
            app.FAstconversiontooLbetweenCONstrainSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = falcons

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FAstconversiontooLbetweenCONstrainSUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FAstconversiontooLbetweenCONstrainSUIFigure)
        end
    end
end